classdef BctFilterDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        Label                  matlab.ui.control.Label
        LoadButton             matlab.ui.control.Button
        ScanButton             matlab.ui.control.Button
        Tree                   matlab.ui.container.Tree
        DataNode               matlab.ui.container.TreeNode
        Node2                  matlab.ui.container.TreeNode
        Node3                  matlab.ui.container.TreeNode
        Node4                  matlab.ui.container.TreeNode
        CenterPanel            matlab.ui.container.Panel
        ViewerPanel            matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        PlayButton             matlab.ui.control.Button
        BackButton             matlab.ui.control.Button
        FilterDesignPanel      matlab.ui.container.Panel
        VelocitySpinner        matlab.ui.control.Spinner
        VelocitySpinnerLabel   matlab.ui.control.Label
        BandwidthSliderLabel   matlab.ui.control.Label
        BandwidthSlider        matlab.ui.control.Slider
        FrequencySliderLabel   matlab.ui.control.Label
        FrequencySlider        matlab.ui.control.Slider
        kbandwidthSliderLabel  matlab.ui.control.Label
        kbandwidthSlider       matlab.ui.control.Slider
        WavenumberSliderLabel  matlab.ui.control.Label
        WavenumberSlider       matlab.ui.control.Slider
        SynthesizeButton       matlab.ui.control.Button
        KernelDropDown         matlab.ui.control.DropDown
        KernelDropDownLabel    matlab.ui.control.Label
        UIAxesKernel           matlab.ui.control.UIAxes
        MeshPanel              matlab.ui.container.Panel
        SaveButton             matlab.ui.control.Button
        vDeltaEditField        matlab.ui.control.NumericEditField
        vDeltaEditFieldLabel   matlab.ui.control.Label
        SignalDropDown         matlab.ui.control.DropDown
        SignalDropDownLabel    matlab.ui.control.Label
        kmodesEditField        matlab.ui.control.NumericEditField
        kmodesEditFieldLabel   matlab.ui.control.Label
        FourierButton          matlab.ui.control.Button
        EigenbasisLabel        matlab.ui.control.Label
        UIAxesResponse         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        BCTObjects struct
        CurrentBCT bct.bct% Description
 SignalObjects struct 
        FilterDesigner
        CurrentFilter
        KronDelta                   % bct.Signal - Kronecker delta on Manifold_Time
        IRSignal                    % bct.Signal - Impulse response after filtering
        CurrentTimePoint
      % Wave packet FFT method properties
        CurrentKernelFFT            % Velocity kernel in Lambda-Omega (FFT domain)
        CurrentOmegaAxis            % Omega axis (rad/s, FFT order)
        CurrentLambdaAxis     
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        % Startup sequence: scan workspace and load default BCT
             % Initialize structures
            app.SignalObjects = struct();
            % Scan workspace for existing BCT objects
            BctBackend.scanWorkspace(app);
            BctBackend.scanSignals(app);
            % Load default BCT object if no objects found in workspace
            if isempty(fieldnames(app.BCTObjects))
                BctBackend.loadDefaultBCT(app);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            node = app.Tree.SelectedNodes;

            if isempty(node)
                uialert(app.UIFigure, 'Please select a BCT object.', 'No Selection');
                return;
            end

            varName = node.NodeData;

            if isempty(varName) || ~isfield(app.BCTObjects, varName)
                uialert(app.UIFigure, 'Selected node is invalid.', 'Invalid Selection');
                return;
            end

            % Load object
            app.CurrentBCT = app.BCTObjects.(varName);

            % Initialize FilterDesigner for this BCT object
            app.FilterDesigner = bct.filters.FilterDesigner(app.CurrentBCT);

            % Create default Joint filter (Lambda × Omega) - delegated to backend
            BctBackend.createDefaultFilter(app);

            % Single high-level update - delegated to backend
            BctBackend.updateUIAfterLoad(app);

        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
             BctBackend.scanWorkspace(app);
        end

        % Callback function
        function ShowMeshButtonPushed(app, event)
            BctBackend.attachViewer(app, app.CurrentBCT);
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)

            % Ensure a BCT object is loaded
            if isempty(app.CurrentBCT)
                uialert(app.UIFigure, 'Please load a BCT object first.', 'No BCT object');
                return;
            end

            B = app.CurrentBCT;

            % Read k from UI
            k = app.kmodesEditField.Value;

            % Validate k
            if ~isscalar(k) || k <= 0 || k > B.Manifold.N
                uialert(app.UIFigure, ...
                    sprintf('k must be between 1 and %d.', B.Manifold.N), ...
                    'Invalid eigenmode count');
                return;
            end

            % Progress dialog
            msg = sprintf('Computing %d Laplace-Beltrami modes... Please wait.', k);
            d = uiprogressdlg(app.UIFigure, 'Message', msg, ...
                'Title', 'Computing Fourier Modes', 'Indeterminate', 'on');

            % Compute eigenbasis using Bct orchestration
            try
                B = B.computeEigenbasis(k);
                app.CurrentBCT = B;  % Update reference
            catch ME
                close(d);
                uialert(app.UIFigure, ME.message, 'Eigenbasis Error');
                return;
            end

            close(d);

            % Update UI (text area, axes, etc.)
            BctBackend.updateUIAfterLoad(app);

            % Now that eigenvalues exist → update slider ranges
            BctBackend.updateKernelSliders(app, B);

            uialert(app.UIFigure, ...
                sprintf('Successfully computed %d eigenmodes.', k), ...
                'Done');

        end

        % Button pushed function: SynthesizeButton
        function SynthesizeButtonPushed(app, event)
         
            % Compute impulse response by filtering KronDelta signal
            % Transforms delta from Manifold_Time → Lambda_Omega → apply filter → Manifold_Time
            
            B = app.CurrentBCT;
            
            % Verify Joint domain exists
            if isempty(B.Joint)
                uialert(app.UIFigure, ...
                    'Joint domain not available. Assign Time domain to BCT first.', ...
                    'No Joint Domain');
                return;
            end
            
            % Verify filter exists
            if isempty(app.CurrentFilter)
                uialert(app.UIFigure, ...
                    'No filter available. Filter should be created on app load.', ...
                    'No Filter');
                return;
            end
            
            % Progress dialog
            d = uiprogressdlg(app.UIFigure, 'Message', 'Creating impulse signal...', ...
                'Title', 'Computing Impulse Response', 'Indeterminate', 'on');
            
            try
                % Create KronDelta signal if it doesn't exist
                if isempty(app.KronDelta)
                    app.KronDelta = BctBackend.createKronDelta(app, B);
                end
                
                % Update progress
                d.Message = 'Applying filter to delta signal...';
                
                % Compute impulse response through filter
                app.IRSignal = BctBackend.impulseResponse(app, B);
                
                % Update progress
                d.Message = 'Updating UI...';
                
                % Update signal dropdown with new signals
         %       BctBackend.updateSignalDropDown(app);
                
                % Visualize filter kernel on actual Lambda-Omega eigenmode grid
                BctBackend.visualizeFilterOnLambdaOmega(app, B, app.UIAxesResponse);
                
                % Update progress
                d.Message = 'Rendering on mesh...';
                
                % Show IRSignal on the mesh viewer (time point 1)
                % Use existing viewer or create if it doesn't exist
                if isempty(B.Viewer) || ~isvalid(B.Viewer)
                    BctBackend.attachViewer(app, B);
                end
                
                % Update the existing viewer with the signal (don't create new one)
                B.Viewer.Children.Color = bct.show.x2rgb(abs(app.IRSignal.Data(:, 1)), 'colormap', 'hot');  % Show first time point
                                
                % Add IRSignal to SignalObjects structure
                if ~isempty(app.IRSignal)
                    signalName = sprintf('IRSignal_%s', datestr(now, 'yyyymmdd_HHMMSS'));
                    app.SignalObjects.(signalName) = app.IRSignal;
                    fprintf('[SynthesizeButton] Added %s to SignalObjects\n', signalName);
                end
                
                % Update signal dropdown
                BctBackend.updateSignalDropDown(app);
                close(d);
                
            catch ME
                close(d);
                uialert(app.UIFigure, ME.message, 'Error Computing Impulse Response');
                rethrow(ME);
            end
            
            fprintf('[SynthesizeButton] Impulse response computed and visualized\n');


        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
             BctBackend.updateKernelPreview(app);
            
        end

        % Value changed function: BandwidthSlider
        function BandwidthSliderValueChanged(app, event)
             BctBackend.updateKernelPreview(app);
            
        end

        % Value changed function: WavenumberSlider
        function WavenumberSliderValueChanged(app, event)
             BctBackend.updateKernelPreview(app);
            
        end

        % Value changed function: kbandwidthSlider
        function kbandwidthSliderValueChanged(app, event)
             BctBackend.updateKernelPreview(app);
            
        end

        % Callback function
        function PlayButtonPushed(app, event)
            if isempty(app.IRSignal)
                warning('BctBackend:NoIRSignal', 'No impulse response signal to step through.');
                return;
            end

            % Use CurrentBCT object and its viewer
            B = app.CurrentBCT;

            if isempty(B) || isempty(B.Viewer) || ~isvalid(B.Viewer)
                warning('BctBackend:NoViewer', 'Viewer not available. Create viewer first.');
                return;
            end

            % Get number of time points
            nTimePoints = size(app.IRSignal.Data, 2);

            % Initialize CurrentTimePoint if it doesn't exist
            if ~isprop(app, 'CurrentTimePoint') || isempty(app.CurrentTimePoint)
                app.CurrentTimePoint = 1;
            end

            % Advance to next time point (wrap around at end)
            app.CurrentTimePoint = app.CurrentTimePoint + 1;
            if app.CurrentTimePoint > nTimePoints
                app.CurrentTimePoint = 1;  % Loop back to start
            end
            dd=app.IRSignal.Data(:, app.CurrentTimePoint);
            % Update viewer with current time point
             app.CurrentBCT.Viewer.Children.Color = bct.show.x2rgb(abs(dd), 'colormap', 'hot');
            drawnow;

            fprintf('[stepSignal] Time point: %d / %d\n', app.CurrentTimePoint, nTimePoints);

        end

        % Value changed function: KernelDropDown
        function KernelDropDownValueChanged(app, event)
                        value = app.KernelDropDown.Value;
            
            % Recreate filter with new kernel type
            BctBackend.createFilterFromKernelType(app, value);
            
            % Update kernel preview
            BctBackend.updateKernelPreview(app);
            
            % Update visibility of velocity spinner based on kernel type
            if strcmpi(value, 'Velocity Gabor')
                app.VelocitySpinner.Visible = 'on';
                app.VelocitySpinnerLabel.Visible = 'on';
            else
                app.VelocitySpinner.Visible = 'off';
                app.VelocitySpinnerLabel.Visible = 'off';
            end
            
        end

        % Value changed function: VelocitySpinner
        function VelocitySpinnerValueChanged(app, event)
                      value = app.VelocitySpinner.Value;
            
            % Update velocity parameter in filter
            BctBackend.updateVelocityParameter(app, value);
            
            % Update kernel preview
            BctBackend.updateKernelPreview(app);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                       % Save all signals to base workspace
            
            savedCount = 0;
            
            % Save KronDelta signal
            if ~isempty(app.KronDelta)
                assignin('base', 'KronDelta', app.KronDelta);
                savedCount = savedCount + 1;
                fprintf('[SaveButton] Saved KronDelta to workspace\n');
            end
            
            % Save IRSignal
            if ~isempty(app.IRSignal)
                assignin('base', 'IRSignal', app.IRSignal);
                savedCount = savedCount + 1;
                fprintf('[SaveButton] Saved IRSignal to workspace\n');
            end
            
            % Save all signals from SignalObjects structure
            if ~isempty(app.SignalObjects)
                signalNames = fieldnames(app.SignalObjects);
                for i = 1:length(signalNames)
                    varName = signalNames{i};
                    assignin('base', varName, app.SignalObjects.(varName));
                    savedCount = savedCount + 1;
                    fprintf('[SaveButton] Saved %s to workspace\n', varName);
                end
            end
            
            % Notify user
            if savedCount > 0
                uialert(app.UIFigure, ...
                    sprintf('Successfully saved %d signal(s) to workspace.', savedCount), ...
                    'Signals Saved', 'Icon', 'success');
            else
                uialert(app.UIFigure, ...
                    'No signals available to save.', ...
                    'No Signals', 'Icon', 'warning');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {746, 746, 746};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.MeshPanel.Layout.Row = 3;
                app.MeshPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {746, 746};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.MeshPanel.Layout.Row = 2;
                app.MeshPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {198, '1x', 385};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.MeshPanel.Layout.Row = 1;
                app.MeshPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel);
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.Node2 = uitreenode(app.DataNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.DataNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.DataNode);
            app.Node4.Text = 'Node4';
            app.ScanButton = uibutton(app.LeftPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.FilterDesignPanel = uipanel(app.CenterPanel);
            app.FilterDesignPanel.Title = 'Filter Design';
            app.UIAxesKernel = uiaxes(app.FilterDesignPanel);
            title(app.UIAxesKernel, 'Title')
            xlabel(app.UIAxesKernel, 'X')
            ylabel(app.UIAxesKernel, 'Y')
            zlabel(app.UIAxesKernel, 'Z')
            app.KernelDropDownLabel = uilabel(app.FilterDesignPanel);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.FilterDesignPanel);
            app.KernelDropDown.Items = {'Gaussian', 'Heat', 'Mexican Hat', 'Gabor', 'Velocity Gabor'};
            app.KernelDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelDropDownValueChanged, true);
            app.KernelDropDown.Value = 'Gaussian';
            app.SynthesizeButton = uibutton(app.FilterDesignPanel, 'push');
            app.SynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeButtonPushed, true);
            app.SynthesizeButton.Text = 'Synthesize';
            app.WavenumberSlider = uislider(app.FilterDesignPanel);
            app.WavenumberSlider.ValueChangedFcn = createCallbackFcn(app, @WavenumberSliderValueChanged, true);
            app.WavenumberSliderLabel = uilabel(app.FilterDesignPanel);
            app.WavenumberSliderLabel.Text = 'Wavenumber';
            app.kbandwidthSlider = uislider(app.FilterDesignPanel);
            app.kbandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @kbandwidthSliderValueChanged, true);
            app.kbandwidthSliderLabel = uilabel(app.FilterDesignPanel);
            app.kbandwidthSliderLabel.Text = 'k bandwidth';
            app.FrequencySlider = uislider(app.FilterDesignPanel);
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.FilterDesignPanel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.BandwidthSlider = uislider(app.FilterDesignPanel);
            app.BandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @BandwidthSliderValueChanged, true);
            app.BandwidthSliderLabel = uilabel(app.FilterDesignPanel);
            app.BandwidthSliderLabel.Text = 'Bandwidth';
            app.VelocitySpinnerLabel = uilabel(app.FilterDesignPanel);
            app.VelocitySpinnerLabel.Text = 'Velocity';
            app.VelocitySpinner = uispinner(app.FilterDesignPanel);
            app.VelocitySpinner.ValueChangedFcn = createCallbackFcn(app, @VelocitySpinnerValueChanged, true);
            app.ViewerPanel = uipanel(app.CenterPanel);
            app.ViewerPanel.Title = 'Viewer';
            app.GridLayout2 = uigridlayout(app.ViewerPanel);
            app.BackButton = uibutton(app.GridLayout2, 'push');
            app.BackButton.Text = 'Back';
            app.PlayButton = uibutton(app.GridLayout2, 'push');
            app.PlayButton.Text = 'Play';
            app.MeshPanel = uipanel(app.GridLayout);
            app.UIAxesResponse = uiaxes(app.MeshPanel);
            title(app.UIAxesResponse, 'Title')
            xlabel(app.UIAxesResponse, 'Frequency (Hz)')
            ylabel(app.UIAxesResponse, 'Wavenumber (k)')
            zlabel(app.UIAxesResponse, 'Z')
            app.EigenbasisLabel = uilabel(app.MeshPanel);
            app.EigenbasisLabel.Text = 'Eigenbasis';
            app.FourierButton = uibutton(app.MeshPanel, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.kmodesEditFieldLabel = uilabel(app.MeshPanel);
            app.kmodesEditFieldLabel.Text = 'k modes';
            app.kmodesEditField = uieditfield(app.MeshPanel, 'numeric');
            app.kmodesEditField.Value = 300;
            app.SignalDropDownLabel = uilabel(app.MeshPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.MeshPanel);
            app.vDeltaEditFieldLabel = uilabel(app.MeshPanel);
            app.vDeltaEditFieldLabel.Text = 'vDelta';
            app.vDeltaEditField = uieditfield(app.MeshPanel, 'numeric');
            app.SaveButton = uibutton(app.MeshPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BctFilterDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end