classdef BctFilterDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        SynthesizeButton         matlab.ui.control.Button
        DesignButton             matlab.ui.control.Button
        ShowMeshButton           matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        ResolutionLabel          matlab.ui.control.Label
        kmodesEditField          matlab.ui.control.NumericEditField
        kmodesEditFieldLabel     matlab.ui.control.Label
        FourierButton            matlab.ui.control.Button
        SpectralLabel            matlab.ui.control.Label
        LoadButton               matlab.ui.control.Button
        ScanButton               matlab.ui.control.Button
        Tree                     matlab.ui.container.Tree
        DataNode                 matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        FilterTypeDropDown       matlab.ui.control.DropDown
        FilterTypeDropDownLabel  matlab.ui.control.Label
        FilterDesignLabel        matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        JointTab                 matlab.ui.container.Tab
        omegaSlider              matlab.ui.control.Slider
        omegaSliderLabel         matlab.ui.control.Label
        sigma_kSlider            matlab.ui.control.Slider
        sigma_kSliderLabel       matlab.ui.control.Label
        sigma_oSlider            matlab.ui.control.Slider
        sigma_oSliderLabel       matlab.ui.control.Label
        k0Slider                 matlab.ui.control.Slider
        k0SliderLabel            matlab.ui.control.Label
        UIAxesResponse           matlab.ui.control.UIAxes
        UIAxesKernel             matlab.ui.control.UIAxes
        SpatialTab               matlab.ui.container.Tab
        SigmaEditField           matlab.ui.control.NumericEditField
        SigmaEditFieldLabel      matlab.ui.control.Label
        Centerk0EditField        matlab.ui.control.NumericEditField
        Centerk0EditFieldLabel   matlab.ui.control.Label
        SpatialparametersLabel   matlab.ui.control.Label
        ShowlambdaButton         matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        TemporalTab              matlab.ui.container.Tab
        SigmafEditField          matlab.ui.control.NumericEditField
        SigmafEditFieldLabel     matlab.ui.control.Label
        Centerf0EditField        matlab.ui.control.NumericEditField
        Centerf0EditFieldLabel   matlab.ui.control.Label
        TemporalparametersLabel  matlab.ui.control.Label
        ShowomegaButton          matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        MeshPanel                matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        BCTObjects struct
        CurrentBCT bct.bct% Description
    end
    
    methods (Access = private)
function scanWorkspace(app)
    % Clear all existing children under DataNode
    delete(app.DataNode.Children);

    % Get all variables from base workspace
    vars = evalin('base', 'whos');

    % Storage for BCTObjects
    app.BCTObjects = struct();

    for i = 1:numel(vars)
        if strcmp(vars(i).class, 'bct.bct')
            varName = vars(i).name;

            % Store the object itself internally
            app.BCTObjects.(varName) = evalin('base', varName);

            % Add a tree node for this variable
            node = uitreenode(app.DataNode);
            node.Text = varName;
            node.NodeData = varName;
        end
    end

    % Expand the tree
    expand(app.Tree);

    % Notify the user if nothing was found
    if isempty(fieldnames(app.BCTObjects))
        uialert(app.UIFigure, ...
            'No variables of type bct.bct were found in the workspace.', ...
            'Workspace Scan');
    end
end

function updateUIAfterLoad(app)
    B = app.CurrentBCT;
    % === Update kernel slider ranges ===
    updateKernelSliders(app);

    % Update text area
    updateTextArea(app, B);

    % Update the joint axes
    updateJointAxes(app, B, app.UIAxesResponse);

end

        
function attachViewer(app, B)
% Attach B.Viewer to the grid layout app.GridLayout2

    parent = app.GridLayout2;

    %--- CASE 1: No viewer exists → create inside grid
    if isempty(B.Viewer) || ~isvalid(B.Viewer)
        B.showMesh('Parent', parent);
        viewer = B.Viewer;

    else
        viewer = B.Viewer;

        %--- CASE 2: Viewer exists but is attached to a standalone figure
        if isa(viewer.Parent, 'matlab.ui.Figure')
            % Recreate inside grid
            B.showMesh('Parent', parent);
            viewer = B.Viewer;
        end
    end

    %==========================
    % Assign layout to fill ALL rows/columns
    %==========================
    
    % Determine number of rows/cols from definitions
    numRows = numel(parent.RowHeight);
    numCols = numel(parent.ColumnWidth);

    % Fill entire grid
    viewer.Layout.Row = [1 numRows];
    viewer.Layout.Column = [1 numCols];

    drawnow;
end


function updateJointAxes(app, B, ax)
%UPDATEJOINTAXES Automatically configure the joint spectral axes
%   Default: wavenumber k (rad/mm)  vs  frequency f (Hz)
%
% %   B  = bct.bct object
% %   ax = handle to uiaxes (e.g., app.UIAxesResponse)
% 
%     if nargin < 3
%         ax = app.UIAxesResponse;   % default joint axes
%     end
% 
%     % ---- Spatial resolution ----
%     kmax = B.Manifold.Resolution.Wavenumber;     % rad/mm
% 
%     % ---- Temporal resolution ----
%     fs   = B.Time.fs;        % sampling frequency
%     nyq  = fs / 2;           % Nyquist (Hz)
% 
%     % ---- Set axes limits ----
%     ax.XLim = [0 nyq];       % frequency axis (Hz)
%     ax.YLim = [0 kmax];      % wavenumber axis (rad/mm)
% 
%     % ---- Set labels ----
%     ax.XLabel.String = 'Frequency (Hz)';
%     ax.YLabel.String = 'Wavenumber k (rad/mm)';
% 
%     % ---- Clear title or set a default ----
%     ax.Title.String = 'Joint Spectrum (k vs f)';
% 
%     % ---- Optional: grid & formatting ----
%     ax.XGrid = 'on';
%     ax.YGrid = 'on';
%UPDATEJOINTAXES Automatically configure the joint spectral axes
%   Default: wavenumber k (rad/mm)  vs  frequency f (Hz)
%
%   B  = bct.bct object
%   ax = handle to uiaxes (e.g., app.UIAxesResponse)

    if nargin < 3
        ax = app.UIAxesResponse;   % default joint axes
    end

    % ---- Spatial resolution (wavenumber) ----
    % Lambda.axis returns wavenumber k = sqrt(lambda) by default
    % Lambda is automatically created as Manifold's dual
    if ~isempty(B.Lambda) && ~isempty(B.Lambda.axis)
        kmax = max(B.Lambda.axis);  % Max wavenumber (rad/mm)
    else
        kmax = 10;  % fallback
    end

    % ---- Temporal resolution ----
    % Omega is automatically created as Time's dual
    if ~isempty(B.Omega) && ~isempty(B.Omega.axis)
        omega_max = max(B.Omega.axis);  % Max angular frequency (rad/s)
        nyq = omega_max / (2*pi);       % Convert to Hz
    elseif ~isempty(B.Time)
        fs = B.Time.fs;        % sampling frequency
        nyq = fs / 2;          % Nyquist (Hz)
    else
        nyq = 25;  % fallback
    end

    % ---- Set axes limits ----
    ax.XLim = [0 nyq];       % frequency axis (Hz)
    ax.YLim = [0 kmax];      % wavenumber axis (rad/mm)

    % ---- Set labels ----
    ax.XLabel.String = 'Frequency (Hz)';
    ax.YLabel.String = 'Wavenumber k (rad/mm)';

    % ---- Clear title or set a default ----
    ax.Title.String = 'Joint Spectrum (k vs f)';

    % ---- Optional: grid & formatting ----
    ax.XGrid = 'on';
    ax.YGrid = 'on';

end

        
function updateTextArea(app, B)
%UPDATETEXTAREA  Populate the text area with mesh + time resolution info.

    % Build spatial info
    spatial_lines = {
        '--- Spatial (Manifold) ---'
        sprintf('Vertices: %d', B.Manifold.N)
        sprintf('Units: %s', B.Manifold.units)
    };
    
    % Lambda is always created as Manifold's dual
    % Check if eigenbasis has been computed (has actual eigenvalues)
    if ~isempty(B.Lambda)
        if ~isempty(B.Lambda.lambda)
            % Eigenbasis computed
            spatial_lines{end+1} = sprintf('Eigenmodes: %d (computed)', B.Lambda.K);
            spatial_lines{end+1} = sprintf('Max wavenumber: %.2f rad/mm', max(B.Lambda.axis));
        else
            % Only estimated axis available
            spatial_lines{end+1} = 'Lambda: estimated (eigenbasis not computed)';
        end
    end
    
    % Build temporal info
    temporal_lines = {''};
    if ~isempty(B.Time)
        temporal_lines = {
            ''
            '--- Temporal (Time) ---'
            sprintf('Samples: %d', B.Time.N)
            sprintf('Sampling rate: %.1f Hz', B.Time.fs)
            sprintf('Duration: %.2f s', B.Time.T/B.Time.fs)
        };
        
        % Omega is automatically created as Time's dual
        if ~isempty(B.Omega)
            temporal_lines{end+1} = sprintf('Omega: %d frequencies', length(B.Omega.axis));
            temporal_lines{end+1} = sprintf('Nyquist: %.1f Hz', max(B.Omega.axis)/(2*pi));
        end
    end

    % Combine and display
    lines = [spatial_lines; temporal_lines];
    
    % Add Joint domain info if available
    if ~isempty(B.Joint)
        sz = B.Joint.size();
        joint_lines = {
            ''
            '--- Joint Domain ---'
            sprintf('Type: %s', B.Joint.Domain)
            sprintf('Grid: [%d×%d]', sz(1), sz(2))
            sprintf('Units: %s', B.Joint.units)
        };
        lines = [lines; joint_lines];
    end
    
    app.TextArea.Value = lines;
end

        
function [L, O] = buildJointGrid(app, lambda_values)
% Build joint (lambda × omega) grid using Joint class
% Uses B.Joint if available, otherwise creates Lambda×Omega joint

    B = app.CurrentBCT;
    
    % Check if Joint domain already exists
    if ~isempty(B.Joint)
        % Use existing Joint domain
        L = B.Joint.A_grid;  % Lambda grid
        O = B.Joint.B_grid;  % Omega grid
    else
        % Create Joint domain from Lambda and Omega
        % Lambda and Omega are automatically created as duals
        if isempty(B.Lambda) || isempty(B.Omega)
            error('BctFilterDesigner:NoLambdaOmega', ...
                'Lambda and Omega domains must exist. Assign Time to BCT first.');
        end
        
        % Create Joint domain
        B = B.createJoint('Lambda', 'Omega');
        app.CurrentBCT = B;  % Update stored reference
        
        % Get grids from newly created Joint
        L = B.Joint.A_grid;  % Lambda grid
        O = B.Joint.B_grid;  % Omega grid
    end
end

        function [L, W, f] = buildFullJointGrid(app, lam)
    % Create lambda axis
    lambda_axis = linspace(min(lam), max(lam), 300);
    
    % Temporal resolution
    if ~isempty(app.CurrentBCT.Time)
        nyq = app.CurrentBCT.Time.fs/2;
        f = linspace(-nyq, nyq, 300);    % frequency (Hz)
    else
        f = linspace(-50, 50, 300);
    end

    omega = 2*pi*f;

    [W, L] = ndgrid(omega, lambda_axis);
end

function fh = buildDefaultJointFilter(app)
% Returns a function handle f(lambda,omega)

    % Default parameters from UI controls
    k0     = app.Centerk0EditField_2.Value;   % center wavenumber
    sigmaK = app.sigmaKEditField.Value;

    omega0 = app.CenteromegaEditField.Value;  % center frequency (rad/s)
    sigmaO = app.sigmaOmegaEditField.Value;

    % Convert center k0 from "wavenumber" back to lambda-domain band center
    % lambda ≈ (2πk)^2  → inverse doesn't matter for visualization.
    % We assume user-entered k0 already corresponds to sqrt(lambda)
    % So we use sqrt(lambda) directly:

    fh = @(lambda, omega) ...
        exp(-0.5*((sqrt(lambda) - k0)./sigmaK).^2) .* ...
        exp(-0.5*((omega - omega0)./sigmaO).^2);
end

        
   function updateKernelSliders(app, B)

    % Retrieve eigenvalues
    lam = B.Manifold.Eigenvalues;
    lambda_max = max(lam);
    k_max = sqrt(lambda_max);

    % Spatial sliders -----------------------------------------
    app.k0Slider.Limits      = [0 k_max];
    app.k0Slider.Value       = k_max/4;

    app.sigma_kSlider.Limits = [k_max/200  k_max/5];
    app.sigma_kSlider.Value  = k_max/20;

    % Temporal sliders ----------------------------------------
    if ~isempty(B.Time)
        nyquist = B.Time.fs/2;
        fmax = nyquist;
    else
        fmax = 50;  % fallback
    end
    
    app.omegaSlider.Limits      = [-2*pi*fmax  2*pi*fmax];
    app.omegaSlider.Value       = 0;

    app.sigma_oSlider.Limits    = [(2*pi*fmax)/200   (2*pi*fmax)/5];
    app.sigma_oSlider.Value     = (2*pi*fmax)/20;

    % After ranges are updated, refresh the kernel preview
    updateKernelPreview(app);
end

        
function KernelSliderMoved(app, event)
    updateKernelPreview(app);
end

        
function updateKernelPreview(app)
    ax = app.UIAxesKernel;

    % Read slider values
    k0     = app.k0Slider.Value;
    sigmaK = app.sigma_kSlider.Value;
    w0     = app.omegaSlider.Value;
    sigmaO = app.sigma_oSlider.Value;

    % Build preview grid
    k = linspace(0, max(app.k0Slider.Limits), 200);
    w = linspace(min(app.omegaSlider.Limits), max(app.omegaSlider.Limits), 200);

    [W, K] = ndgrid(w, k);

    % Kernel
    F = exp(-0.5*((K - k0)./sigmaK).^2) .* ...
        exp(-0.5*((W - w0)./sigmaO).^2);

    % Plot
    cla(ax);
    imagesc(ax, k, w/(2*pi), F);
    axis(ax,'xy');
    xlabel(ax, 'Wavenumber k');
    ylabel(ax, 'Frequency (Hz)');
    title(ax, 'Kernel Preview');
    colormap(ax, 'turbo');
end

        function visualizeJointFilter(app, freq, L, F)
    % Visualizes joint filter on UIAxes_3 (or any axes 'ax')
    ax = app.UIAxesResponse;
    cla(ax);

    % surf plot (freq × lambda)
    surf(ax, freq, L, F, 'EdgeColor','none');

    xlabel(ax, 'Frequency (Hz)');
    ylabel(ax, 'Wavenumber k (sqrt(\lambda))');
    zlabel(ax, 'Magnitude');
    title(ax, 'Joint Filter Response');

    colormap(ax, 'turbo');
    shading(ax, 'interp');
    view(ax, 2);   % 2D heatmap view

    colorbar(ax);
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
    node = app.Tree.SelectedNodes;

    if isempty(node)
        uialert(app.UIFigure, 'Please select a BCT object.', 'No Selection');
        return;
    end

    varName = node.NodeData;

    if isempty(varName) || ~isfield(app.BCTObjects, varName)
        uialert(app.UIFigure, 'Selected node is invalid.', 'Invalid Selection');
        return;
    end

    % Load object
    app.CurrentBCT = app.BCTObjects.(varName);

    % Attach mesh viewer (your custom function)
   % attachViewer(app, app.CurrentBCT);

    % Single high-level update
    updateUIAfterLoad(app);

    uialert(app.UIFigure, sprintf('Loaded BCT object: %s', varName), 'Success');
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
             scanWorkspace(app);
        end

        % Button pushed function: ShowMeshButton
        function ShowMeshButtonPushed(app, event)
            attachViewer(app, app.CurrentBCT);
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
    % Ensure a BCT object is loaded
    if isempty(app.CurrentBCT)
        uialert(app.UIFigure, 'Please load a BCT object first.', 'No BCT object');
        return;
    end

    B = app.CurrentBCT;

    % Read k from UI
    k = app.kmodesEditField.Value;

    % Validate k
    if ~isscalar(k) || k <= 0 || k > B.Manifold.N
        uialert(app.UIFigure, ...
            sprintf('k must be between 1 and %d.', B.Manifold.N), ...
            'Invalid eigenmode count');
        return;
    end

    % Progress dialog
    msg = sprintf('Computing %d Laplace-Beltrami modes... Please wait.', k);
    d = uiprogressdlg(app.UIFigure, 'Message', msg, ...
        'Title', 'Computing Fourier Modes', 'Indeterminate', 'on');

    % Compute eigenmodes (this automatically updates Manifold properties)
    try
        B.Manifold.meshFourier(k);
    catch ME
        close(d);
        uialert(app.UIFigure, ME.message, 'Fourier Error');
        return;
    end

    close(d);

    % Update UI (text area, axes, etc.)
    updateUIAfterLoad(app);

    % Now that eigenvalues exist → update slider ranges
    updateKernelSliders(app, B);

    uialert(app.UIFigure, ...
        sprintf('Successfully computed %d eigenmodes.', k), ...
        'Done');
        end

        % Button pushed function: SynthesizeButton
        function SynthesizeButtonPushed(app, event)
               B = app.CurrentBCT;

    % Get eigenvalues
    lam = B.Manifold.Eigenvalues(:);

    % Build full joint grid
    [LambdaGrid, OmegaGrid, freq_axis] = buildFullJointGrid(app, lam);

    % Build kernel function from sliders
    kernel = @(l, w) ...
        exp(-0.5*((sqrt(l)-app.k0Slider.Value)/app.sigma_kSlider.Value).^2) .* ...
        exp(-0.5*((w - app.omegaSlider.Value)/app.sigma_oSlider.Value).^2);

    % Evaluate
    F = kernel(LambdaGrid, OmegaGrid);

    % Visualize in Joint UIAxes
    visualizeJointFilter(app, freq_axis, LambdaGrid, F);
        end

        % Value changed function: omegaSlider
        function omegaSliderValueChanged(app, event)
             updateKernelPreview(app);
            
        end

        % Value changed function: sigma_oSlider
        function sigma_oSliderValueChanged(app, event)
             updateKernelPreview(app);
            
        end

        % Value changed function: k0Slider
        function k0SliderValueChanged(app, event)
             updateKernelPreview(app);
            
        end

        % Value changed function: sigma_kSlider
        function sigma_kSliderValueChanged(app, event)
             updateKernelPreview(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {746, 746, 746};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.MeshPanel.Layout.Row = 3;
                app.MeshPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {746, 746};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.MeshPanel.Layout.Row = 2;
                app.MeshPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 529};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.MeshPanel.Layout.Row = 1;
                app.MeshPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FilterDesignLabel = uilabel(app.LeftPanel);
            app.FilterDesignLabel.Text = 'Filter Design';
            app.FilterTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.LeftPanel);
            app.FilterTypeDropDown.Items = {'Spatial', 'Temporal', 'Joint Separable', 'Joint', 'Dynamic'};
            app.FilterTypeDropDown.Value = 'Joint';
            app.Tree = uitree(app.LeftPanel);
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.Node2 = uitreenode(app.DataNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.DataNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.DataNode);
            app.Node4.Text = 'Node4';
            app.ScanButton = uibutton(app.LeftPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SpectralLabel = uilabel(app.LeftPanel);
            app.SpectralLabel.Text = 'Spectral ';
            app.FourierButton = uibutton(app.LeftPanel, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.kmodesEditFieldLabel = uilabel(app.LeftPanel);
            app.kmodesEditFieldLabel.Text = 'k modes';
            app.kmodesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.kmodesEditField.Value = 300;
            app.ResolutionLabel = uilabel(app.LeftPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.TextArea = uitextarea(app.LeftPanel);
            app.ShowMeshButton = uibutton(app.LeftPanel, 'push');
            app.ShowMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshButtonPushed, true);
            app.ShowMeshButton.Text = 'Show Mesh';
            app.DesignButton = uibutton(app.LeftPanel, 'push');
            app.DesignButton.Text = 'Design';
            app.SynthesizeButton = uibutton(app.LeftPanel, 'push');
            app.SynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeButtonPushed, true);
            app.SynthesizeButton.Text = 'Synthesize';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.JointTab = uitab(app.TabGroup);
            app.JointTab.Title = 'Joint';
            app.UIAxesKernel = uiaxes(app.JointTab);
            title(app.UIAxesKernel, 'Title')
            xlabel(app.UIAxesKernel, 'X')
            ylabel(app.UIAxesKernel, 'Y')
            zlabel(app.UIAxesKernel, 'Z')
            app.UIAxesResponse = uiaxes(app.JointTab);
            title(app.UIAxesResponse, 'Title')
            xlabel(app.UIAxesResponse, 'Frequency (Hz)')
            ylabel(app.UIAxesResponse, 'Wavenumber (k)')
            zlabel(app.UIAxesResponse, 'Z')
            app.k0SliderLabel = uilabel(app.JointTab);
            app.k0SliderLabel.Text = 'k0';
            app.k0Slider = uislider(app.JointTab);
            app.k0Slider.ValueChangedFcn = createCallbackFcn(app, @k0SliderValueChanged, true);
            app.sigma_oSliderLabel = uilabel(app.JointTab);
            app.sigma_oSliderLabel.Text = 'sigma_o';
            app.sigma_oSlider = uislider(app.JointTab);
            app.sigma_oSlider.ValueChangedFcn = createCallbackFcn(app, @sigma_oSliderValueChanged, true);
            app.sigma_kSliderLabel = uilabel(app.JointTab);
            app.sigma_kSliderLabel.Text = 'sigma_k';
            app.sigma_kSlider = uislider(app.JointTab);
            app.sigma_kSlider.ValueChangedFcn = createCallbackFcn(app, @sigma_kSliderValueChanged, true);
            app.omegaSliderLabel = uilabel(app.JointTab);
            app.omegaSliderLabel.Text = 'omega';
            app.omegaSlider = uislider(app.JointTab);
            app.omegaSlider.ValueChangedFcn = createCallbackFcn(app, @omegaSliderValueChanged, true);
            app.SpatialTab = uitab(app.TabGroup);
            app.SpatialTab.Title = 'Spatial ';
            app.UIAxes = uiaxes(app.SpatialTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Wavenumber (k)')
            zlabel(app.UIAxes, 'Z')
            app.ShowlambdaButton = uibutton(app.SpatialTab, 'push');
            app.ShowlambdaButton.Text = 'Show lambda';
            app.SpatialparametersLabel = uilabel(app.SpatialTab);
            app.SpatialparametersLabel.Text = 'Spatial parameters';
            app.Centerk0EditFieldLabel = uilabel(app.SpatialTab);
            app.Centerk0EditFieldLabel.Text = 'Center k0';
            app.Centerk0EditField = uieditfield(app.SpatialTab, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.SpatialTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.SpatialTab, 'numeric');
            app.TemporalTab = uitab(app.TabGroup);
            app.TemporalTab.Title = 'Temporal';
            app.UIAxes_2 = uiaxes(app.TemporalTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Wavenumber (k)')
            zlabel(app.UIAxes_2, 'Z')
            app.ShowomegaButton = uibutton(app.TemporalTab, 'push');
            app.ShowomegaButton.Text = 'Show omega';
            app.TemporalparametersLabel = uilabel(app.TemporalTab);
            app.TemporalparametersLabel.Text = 'Temporal parameters';
            app.Centerf0EditFieldLabel = uilabel(app.TemporalTab);
            app.Centerf0EditFieldLabel.Text = 'Center f0';
            app.Centerf0EditField = uieditfield(app.TemporalTab, 'numeric');
            app.SigmafEditFieldLabel = uilabel(app.TemporalTab);
            app.SigmafEditFieldLabel.Text = 'Sigma f';
            app.SigmafEditField = uieditfield(app.TemporalTab, 'numeric');
            app.MeshPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.MeshPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BctFilterDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end