classdef BctFilterDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TextArea                   matlab.ui.control.TextArea
        ResolutionLabel            matlab.ui.control.Label
        kmodesEditField            matlab.ui.control.NumericEditField
        kmodesEditFieldLabel       matlab.ui.control.Label
        FourierButton              matlab.ui.control.Button
        SpectralLabel              matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        ScanButton                 matlab.ui.control.Button
        Tree                       matlab.ui.container.Tree
        DataNode                   matlab.ui.container.TreeNode
        Node4                      matlab.ui.container.TreeNode
        Node3                      matlab.ui.container.TreeNode
        Node2                      matlab.ui.container.TreeNode
        FilterTypeDropDown         matlab.ui.control.DropDown
        FilterTypeDropDownLabel    matlab.ui.control.Label
        FilterDesignLabel          matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        JointTab                   matlab.ui.container.Tab
        sigmaOmegaEditField        matlab.ui.control.NumericEditField
        sigmaOmegaEditFieldLabel   matlab.ui.control.Label
        sigmaKEditField            matlab.ui.control.NumericEditField
        sigmaKEditFieldLabel       matlab.ui.control.Label
        CenteromegaEditField       matlab.ui.control.NumericEditField
        CenteromegaEditFieldLabel  matlab.ui.control.Label
        Centerk0EditField_2        matlab.ui.control.NumericEditField
        Centerk0EditField_2Label   matlab.ui.control.Label
        WavepacketLabel            matlab.ui.control.Label
        UIAxes_3                   matlab.ui.control.UIAxes
        SpatialTab                 matlab.ui.container.Tab
        SigmaEditField             matlab.ui.control.NumericEditField
        SigmaEditFieldLabel        matlab.ui.control.Label
        Centerk0EditField          matlab.ui.control.NumericEditField
        Centerk0EditFieldLabel     matlab.ui.control.Label
        SpatialparametersLabel     matlab.ui.control.Label
        ShowlambdaButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        TemporalTab                matlab.ui.container.Tab
        SigmafEditField            matlab.ui.control.NumericEditField
        SigmafEditFieldLabel       matlab.ui.control.Label
        Centerf0EditField          matlab.ui.control.NumericEditField
        Centerf0EditFieldLabel     matlab.ui.control.Label
        TemporalparametersLabel    matlab.ui.control.Label
        ShowomegaButton            matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        MeshPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        BCTObjects struct
        CurrentBCT bct.bct% Description
    end
    
    methods (Access = private)
function scanWorkspace(app)
    % Clear all existing children under DataNode
    delete(app.DataNode.Children);

    % Get all variables from base workspace
    vars = evalin('base', 'whos');

    % Storage for BCTObjects
    app.BCTObjects = struct();

    for i = 1:numel(vars)
        if strcmp(vars(i).class, 'bct.bct')
            varName = vars(i).name;

            % Store the object itself internally
            app.BCTObjects.(varName) = evalin('base', varName);

            % Add a tree node for this variable
            node = uitreenode(app.DataNode);
            node.Text = varName;
            node.NodeData = varName;
        end
    end

    % Expand the tree
    expand(app.Tree);

    % Notify the user if nothing was found
    if isempty(fieldnames(app.BCTObjects))
        uialert(app.UIFigure, ...
            'No variables of type bct.bct were found in the workspace.', ...
            'Workspace Scan');
    end
end

function updateUIAfterLoad(app)
    B = app.CurrentBCT;

    % Extract spatial resolution
    R = B.Manifold.Resolution;

    % Extract time info
    T = B.Time;

    % Build the text lines
    lines = {
        sprintf('--- Spatial Resolution ---')
        sprintf('Units: %s', R.Units)
        sprintf('Wavelength:        %.4f mm', R.Mesh.Wavelength)
        sprintf('Spatial Frequency: %.4f cycles/mm', R.Mesh.SpatialFrequency)
        sprintf('Wavenumber:        %.4f rad/mm', R.Mesh.Wavenumber)
        sprintf('lambda_{max}:      %.4f', R.Mesh.lambda_max)
        ''
        sprintf('--- Temporal Resolution ---')
        sprintf('T:        %d samples', T.T)
        sprintf('fs:       %.2f Hz', T.fs)
        sprintf('Duration: %.4f seconds', T.Duration)
        sprintf('Nyquist:  %.2f Hz', T.Nyquist)
    };

    % Display in textarea
    app.TextArea.Value = lines;

end

        
function attachViewer(app, B)
    % Ensure B has a viewer
    if isempty(B.Viewer) || ~isvalid(B.Viewer)
        uialert(app.UIFigure, 'This BCT object has no valid mesh viewer.', 'Viewer Error');
        return;
    end

    % Reparent viewer figure to App panel
    B.Viewer.Parent = app.MeshPanel;

    % Resize to fill the panel
    B.Viewer.Position = [1 1 app.MeshPanel.Position(3) app.MeshPanel.Position(4)];

    % Turn off external figure behavior
    if isa(B.Viewer.Parent, 'matlab.ui.Figure')
        B.Viewer.Parent.Visible = 'off';
    end

    % Force layout update
    drawnow;
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
 node = app.Tree.SelectedNodes;

    % Ensure something is selected
    if isempty(node)
        uialert(app.UIFigure, 'Please select a BCT object from the tree.', 'No Selection');
        return;
    end

    varName = node.NodeData;

    if isempty(varName) || ~isfield(app.BCTObjects, varName)
        uialert(app.UIFigure, 'Selected node has no associated BCT object.', 'Invalid Selection');
        return;
    end

    % Load the selected object
    app.CurrentBCT = app.BCTObjects.(varName);

    % Attach its mesh viewer to the app
    attachViewer(app, app.CurrentBCT);   % <--- ADD THIS LINE

    % Notify the user
    uialert(app.UIFigure, ...
        sprintf('Loaded BCT object: %s', varName), ...
        'Success');

    % Update all other UI portions
    updateUIAfterLoad(app);
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
             scanWorkspace(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {746, 746, 746};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.MeshPanel.Layout.Row = 3;
                app.MeshPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {746, 746};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.MeshPanel.Layout.Row = 2;
                app.MeshPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 529};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.MeshPanel.Layout.Row = 1;
                app.MeshPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FilterDesignLabel = uilabel(app.LeftPanel);
            app.FilterDesignLabel.Text = 'Filter Design';
            app.FilterTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.LeftPanel);
            app.FilterTypeDropDown.Items = {'Spatial', 'Temporal', 'Joint Separable', 'Joint', 'Dynamic'};
            app.FilterTypeDropDown.Value = 'Joint';
            app.Tree = uitree(app.LeftPanel);
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.Node2 = uitreenode(app.DataNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.DataNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.DataNode);
            app.Node4.Text = 'Node4';
            app.ScanButton = uibutton(app.LeftPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SpectralLabel = uilabel(app.LeftPanel);
            app.SpectralLabel.Text = 'Spectral ';
            app.FourierButton = uibutton(app.LeftPanel, 'push');
            app.FourierButton.Text = 'Fourier';
            app.kmodesEditFieldLabel = uilabel(app.LeftPanel);
            app.kmodesEditFieldLabel.Text = 'k modes';
            app.kmodesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ResolutionLabel = uilabel(app.LeftPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.TextArea = uitextarea(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.JointTab = uitab(app.TabGroup);
            app.JointTab.Title = 'Joint';
            app.UIAxes_3 = uiaxes(app.JointTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'Frequency (Hz)')
            ylabel(app.UIAxes_3, 'Wavenumber (k)')
            zlabel(app.UIAxes_3, 'Z')
            app.WavepacketLabel = uilabel(app.JointTab);
            app.WavepacketLabel.Text = 'Wave packet';
            app.Centerk0EditField_2Label = uilabel(app.JointTab);
            app.Centerk0EditField_2Label.Text = 'Center k0';
            app.Centerk0EditField_2 = uieditfield(app.JointTab, 'numeric');
            app.CenteromegaEditFieldLabel = uilabel(app.JointTab);
            app.CenteromegaEditFieldLabel.Text = 'Center omega';
            app.CenteromegaEditField = uieditfield(app.JointTab, 'numeric');
            app.sigmaKEditFieldLabel = uilabel(app.JointTab);
            app.sigmaKEditFieldLabel.Text = 'sigmaK';
            app.sigmaKEditField = uieditfield(app.JointTab, 'numeric');
            app.sigmaOmegaEditFieldLabel = uilabel(app.JointTab);
            app.sigmaOmegaEditFieldLabel.Text = 'sigmaOmega';
            app.sigmaOmegaEditField = uieditfield(app.JointTab, 'numeric');
            app.SpatialTab = uitab(app.TabGroup);
            app.SpatialTab.Title = 'Spatial ';
            app.UIAxes = uiaxes(app.SpatialTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Wavenumber (k)')
            zlabel(app.UIAxes, 'Z')
            app.ShowlambdaButton = uibutton(app.SpatialTab, 'push');
            app.ShowlambdaButton.Text = 'Show lambda';
            app.SpatialparametersLabel = uilabel(app.SpatialTab);
            app.SpatialparametersLabel.Text = 'Spatial parameters';
            app.Centerk0EditFieldLabel = uilabel(app.SpatialTab);
            app.Centerk0EditFieldLabel.Text = 'Center k0';
            app.Centerk0EditField = uieditfield(app.SpatialTab, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.SpatialTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.SpatialTab, 'numeric');
            app.TemporalTab = uitab(app.TabGroup);
            app.TemporalTab.Title = 'Temporal';
            app.UIAxes_2 = uiaxes(app.TemporalTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Wavenumber (k)')
            zlabel(app.UIAxes_2, 'Z')
            app.ShowomegaButton = uibutton(app.TemporalTab, 'push');
            app.ShowomegaButton.Text = 'Show omega';
            app.TemporalparametersLabel = uilabel(app.TemporalTab);
            app.TemporalparametersLabel.Text = 'Temporal parameters';
            app.Centerf0EditFieldLabel = uilabel(app.TemporalTab);
            app.Centerf0EditFieldLabel.Text = 'Center f0';
            app.Centerf0EditField = uieditfield(app.TemporalTab, 'numeric');
            app.SigmafEditFieldLabel = uilabel(app.TemporalTab);
            app.SigmafEditFieldLabel.Text = 'Sigma f';
            app.SigmafEditField = uieditfield(app.TemporalTab, 'numeric');
            app.MeshPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BctFilterDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end